classdef main_app_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        AddinputFileTab          matlab.ui.container.Tab
        InputFileLabel           matlab.ui.control.Label
        ip_path_field            matlab.ui.control.EditField
        MaxGainEditField_2Label  matlab.ui.control.Label
        max_gain_field           matlab.ui.control.NumericEditField
        SaturationPowerEditField_2Label  matlab.ui.control.Label
        P_Sat_field              matlab.ui.control.NumericEditField
        Trv_33_field             matlab.ui.control.NumericEditField
        Trv_44_field             matlab.ui.control.NumericEditField
        Trv_22_field             matlab.ui.control.NumericEditField
        FrequncyTransitionVectorEditFieldLabel_2  matlab.ui.control.Label
        Trv_11_field             matlab.ui.control.NumericEditField
        TransformSignalButton    matlab.ui.control.StateButton
        RecordAudioTab           matlab.ui.container.Tab
        MaxGainEditFieldLabel    matlab.ui.control.Label
        max_gain_field2          matlab.ui.control.NumericEditField
        SaturationPowerLabel     matlab.ui.control.Label
        P_sat_field2             matlab.ui.control.NumericEditField
        trv_1_field              matlab.ui.control.NumericEditField
        trv_2_field              matlab.ui.control.NumericEditField
        trv_3_field              matlab.ui.control.NumericEditField
        trv_4_field              matlab.ui.control.NumericEditField
        RecordAudioButton        matlab.ui.control.Button
        NoofsecondsSpinnerLabel  matlab.ui.control.Label
        NoofsecondsSpinner       matlab.ui.control.Spinner
        TransitionVectorLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        ip
        fsr
        max_gain
        p_sat
        trv_1
        trv_2
        trv_3
        trv_4
         % Record Tab
        timer
    end
    
    methods (Access = private)
        function func(app)
            app.ip = app.ip(:,1);
            main(app.ip, app.fsr, app.max_gain, app.p_sat, app.trv_1, app.trv_2, app.trv_3, app.trv_4)
        end
    end
    

    methods (Access = private)

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            recObj = audiorecorder;
            recordblocking(recObj, app.timer);
            y  =getaudiodata(recObj);
            app.ip = y;
            app.fsr = 8000;
            func(app);
        end

        % Value changed function: TransformSignalButton
        function TransformSignalButtonValueChanged(app, event)
            func(app);
        end

        % Value changed function: ip_path_field
        function ip_path_fieldValueChanged(app, event)
            value = app.ip_path_field.Value;
            [app.ip,app.fsr] = audioread(value);            
        end

        % Value changed function: max_gain_field
        function max_gain_fieldValueChanged(app, event)
            value = app.max_gain_field.Value;
            app.max_gain = value;
        end

        % Value changed function: NoofsecondsSpinner
        function NoofsecondsSpinnerValueChanged(app, event)
            value = app.NoofsecondsSpinner.Value;
            app.timer = value;
        end

        % Value changed function: P_Sat_field
        function P_Sat_fieldValueChanged(app, event)
            value = app.P_Sat_field.Value;
            app.p_sat = value;
        end

        % Value changed function: Trv_33_field
        function Trv_33_fieldValueChanged(app, event)
            value = app.Trv_33_field.Value;
            app.trv_3 = value;
        end

        % Value changed function: Trv_44_field
        function Trv_44_fieldValueChanged(app, event)
            value = app.Trv_44_field.Value;
            app.trv_4 = value;
        end

        % Value changed function: Trv_22_field
        function Trv_22_fieldValueChanged(app, event)
            value = app.Trv_22_field.Value;
            app.trv_2 = value;
        end

        % Value changed function: Trv_11_field
        function Trv_11_fieldValueChanged(app, event)
            value = app.Trv_11_field.Value;
            app.trv_1 = value;
        end

        % Value changed function: max_gain_field2
        function max_gain_field2ValueChanged(app, event)
            value = app.max_gain_field2.Value;
            app.max_gain = value;
        end

        % Value changed function: P_sat_field2
        function P_sat_field2ValueChanged(app, event)
            value = app.P_sat_field2.Value;
            app.p_sat = value;
        end

        % Value changed function: trv_1_field
        function trv_1_fieldValueChanged(app, event)
            value = app.trv_1_field.Value;
            app.trv_1 = value;
        end

        % Value changed function: trv_2_field
        function trv_2_fieldValueChanged(app, event)
            value = app.trv_2_field.Value;
            app.trv_2 = value;
        end

        % Value changed function: trv_3_field
        function trv_3_fieldValueChanged(app, event)
            value = app.trv_3_field.Value;
            app.trv_3 = value;
        end

        % Value changed function: trv_4_field
        function trv_4_fieldValueChanged(app, event)
            value = app.trv_4_field.Value;
            app.trv_4 = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AddinputFileTab = uitab(app.TabGroup);
            app.AddinputFileTab.Title = 'Add input File';
            app.InputFileLabel = uilabel(app.AddinputFileTab);
            app.InputFileLabel.Text = 'Input-File:';
            app.ip_path_field = uieditfield(app.AddinputFileTab, 'text');
            app.ip_path_field.ValueChangedFcn = createCallbackFcn(app, @ip_path_fieldValueChanged, true);
            app.MaxGainEditField_2Label = uilabel(app.AddinputFileTab);
            app.MaxGainEditField_2Label.Text = 'Max Gain:';
            app.max_gain_field = uieditfield(app.AddinputFileTab, 'numeric');
            app.max_gain_field.ValueChangedFcn = createCallbackFcn(app, @max_gain_fieldValueChanged, true);
            app.SaturationPowerEditField_2Label = uilabel(app.AddinputFileTab);
            app.SaturationPowerEditField_2Label.Text = 'Saturation Power: ';
            app.P_Sat_field = uieditfield(app.AddinputFileTab, 'numeric');
            app.P_Sat_field.ValueChangedFcn = createCallbackFcn(app, @P_Sat_fieldValueChanged, true);
            app.Trv_33_field = uieditfield(app.AddinputFileTab, 'numeric');
            app.Trv_33_field.ValueChangedFcn = createCallbackFcn(app, @Trv_33_fieldValueChanged, true);
            app.Trv_44_field = uieditfield(app.AddinputFileTab, 'numeric');
            app.Trv_44_field.ValueChangedFcn = createCallbackFcn(app, @Trv_44_fieldValueChanged, true);
            app.Trv_22_field = uieditfield(app.AddinputFileTab, 'numeric');
            app.Trv_22_field.ValueChangedFcn = createCallbackFcn(app, @Trv_22_fieldValueChanged, true);
            app.FrequncyTransitionVectorEditFieldLabel_2 = uilabel(app.AddinputFileTab);
            app.FrequncyTransitionVectorEditFieldLabel_2.Text = 'Frequncy Transition Vector';
            app.Trv_11_field = uieditfield(app.AddinputFileTab, 'numeric');
            app.Trv_11_field.ValueChangedFcn = createCallbackFcn(app, @Trv_11_fieldValueChanged, true);
            app.TransformSignalButton = uibutton(app.AddinputFileTab, 'state');
            app.TransformSignalButton.ValueChangedFcn = createCallbackFcn(app, @TransformSignalButtonValueChanged, true);
            app.TransformSignalButton.Text = 'Transform Signal';
            app.RecordAudioTab = uitab(app.TabGroup);
            app.RecordAudioTab.Title = 'Record Audio';
            app.MaxGainEditFieldLabel = uilabel(app.RecordAudioTab);
            app.MaxGainEditFieldLabel.Text = 'Max Gain:';
            app.max_gain_field2 = uieditfield(app.RecordAudioTab, 'numeric');
            app.max_gain_field2.ValueChangedFcn = createCallbackFcn(app, @max_gain_field2ValueChanged, true);
            app.SaturationPowerLabel = uilabel(app.RecordAudioTab);
            app.SaturationPowerLabel.Text = 'Saturation Power: ';
            app.P_sat_field2 = uieditfield(app.RecordAudioTab, 'numeric');
            app.P_sat_field2.ValueChangedFcn = createCallbackFcn(app, @P_sat_field2ValueChanged, true);
            app.trv_1_field = uieditfield(app.RecordAudioTab, 'numeric');
            app.trv_1_field.ValueChangedFcn = createCallbackFcn(app, @trv_1_fieldValueChanged, true);
            app.trv_2_field = uieditfield(app.RecordAudioTab, 'numeric');
            app.trv_2_field.ValueChangedFcn = createCallbackFcn(app, @trv_2_fieldValueChanged, true);
            app.trv_3_field = uieditfield(app.RecordAudioTab, 'numeric');
            app.trv_3_field.ValueChangedFcn = createCallbackFcn(app, @trv_3_fieldValueChanged, true);
            app.trv_4_field = uieditfield(app.RecordAudioTab, 'numeric');
            app.trv_4_field.ValueChangedFcn = createCallbackFcn(app, @trv_4_fieldValueChanged, true);
            app.RecordAudioButton = uibutton(app.RecordAudioTab, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.NoofsecondsSpinnerLabel = uilabel(app.RecordAudioTab);
            app.NoofsecondsSpinnerLabel.Text = 'No.of seconds';
            app.NoofsecondsSpinner = uispinner(app.RecordAudioTab);
            app.NoofsecondsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofsecondsSpinnerValueChanged, true);
            app.TransitionVectorLabel = uilabel(app.RecordAudioTab);
            app.TransitionVectorLabel.Text = 'Transition Vector:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_app_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end